classdef SoundTransitionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        CutAudioSliderLabel      matlab.ui.control.Label
        CutAudioSlider           matlab.ui.control.Slider
        CutAudioSlider_2Label    matlab.ui.control.Label
        CutAudioSlider_2         matlab.ui.control.Slider
        TestPlayButton           matlab.ui.control.Button
        TestPlayButton_2         matlab.ui.control.Button
        PlaybackDropDown_3Label  matlab.ui.control.Label
        PlaybackDropDown         matlab.ui.control.DropDown
        LoadMusicAButton         matlab.ui.control.Button
        LoadMusicBButton         matlab.ui.control.Button
        PlaybackDropDown_4Label  matlab.ui.control.Label
        PlaybackDropDown_2       matlab.ui.control.DropDown
        TransitionButton         matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        ExportButton             matlab.ui.control.Button
        DJMixerLabel             matlab.ui.control.Label
        StudentNameLabel         matlab.ui.control.Label
        StudentIDLabel           matlab.ui.control.Label
        ABDULLAHISHEHULabel      matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        HelpButton               matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        music_a % Loaded Music A
        freq_a
        music_b 
        freq_b
    end
    
    properties (Access = public)
        player_a % Description
        player_b
        player_mixed
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMusicAButton
        function LoadMusicAButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav; *.mp3; *.m4a'});
            fullpath = fullfile(path, file);
            try
                [app.music_a, app.freq_a] = audioread(fullpath);
            catch
                return                
            end
            
            amp = app.music_a(:, 1);
            period = 1/app.freq_a;
            time = 0:period:(length(amp)*period - period);
            
            plot(app.UIAxes, time, amp);
            app.UIAxes.XLim = [0, int16(length(amp)*period - period)];
            
            app.CutAudioSlider.Limits = [0, round(max(time))];
            
            app.TestPlayButton.Enable = true;
        end

        % Button pushed function: TestPlayButton
        function TestPlayButtonPushed(app, event)
            drpdwnValue = str2double(app.PlaybackDropDown.Value);
            speed = round(drpdwnValue * app.freq_a);
            app.player_a = audioplayer(app.music_a, speed);
            play(app.player_a)
        end

        % Button pushed function: LoadMusicBButton
        function LoadMusicBButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav; *.mp3; *.m4a'});
            fullpath = fullfile(path, file);
            try
                [app.music_b, app.freq_b] = audioread(fullpath);
            catch
                return                
            end
            
            amp = app.music_b(:, 1);
            period = 1/app.freq_b;
            time = 0:period:(length(amp)*period - period);
            
            plot(app.UIAxes2, time, amp);
            app.UIAxes2.XLim = [0, int16(length(amp)*period - period)];
            
            app.CutAudioSlider_2.Limits = [0, round(max(time))];
            
            app.TestPlayButton_2.Enable = true;
        end

        % Button pushed function: TestPlayButton_2
        function TestPlayButton_2Pushed(app, event)
            drpdwnValue = str2double(app.PlaybackDropDown_2.Value);
            speed = round(drpdwnValue * app.freq_b);
            app.player_b = audioplayer(app.music_b, speed);
            play(app.player_b)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Music 1')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Music 2')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            app.CutAudioSliderLabel = uilabel(app.UIFigure);
            app.CutAudioSliderLabel.Text = 'Cut Audio';
            app.CutAudioSlider = uislider(app.UIFigure);
            app.CutAudioSlider_2Label = uilabel(app.UIFigure);
            app.CutAudioSlider_2Label.Text = 'Cut Audio';
            app.CutAudioSlider_2 = uislider(app.UIFigure);
            app.TestPlayButton = uibutton(app.UIFigure, 'push');
            app.TestPlayButton.ButtonPushedFcn = createCallbackFcn(app, @TestPlayButtonPushed, true);
            app.TestPlayButton.Enable = 'off';
            app.TestPlayButton.Text = 'Test Play';
            app.TestPlayButton_2 = uibutton(app.UIFigure, 'push');
            app.TestPlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @TestPlayButton_2Pushed, true);
            app.TestPlayButton_2.Enable = 'off';
            app.TestPlayButton_2.Text = 'Test Play';
            app.PlaybackDropDown_3Label = uilabel(app.UIFigure);
            app.PlaybackDropDown_3Label.Text = 'Playback';
            app.PlaybackDropDown = uidropdown(app.UIFigure);
            app.PlaybackDropDown.Items = {'Very Slow', 'Slow', 'Normal', 'Fast', 'Very Fast'};
            app.PlaybackDropDown.ItemsData = {'0.25', '0.5', '1', '1.5', '2'};
            app.PlaybackDropDown.Value = '1';
            app.LoadMusicAButton = uibutton(app.UIFigure, 'push');
            app.LoadMusicAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMusicAButtonPushed, true);
            app.LoadMusicAButton.Text = 'Load Music A';
            app.LoadMusicBButton = uibutton(app.UIFigure, 'push');
            app.LoadMusicBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMusicBButtonPushed, true);
            app.LoadMusicBButton.Text = 'Load Music B';
            app.PlaybackDropDown_4Label = uilabel(app.UIFigure);
            app.PlaybackDropDown_4Label.Text = 'Playback';
            app.PlaybackDropDown_2 = uidropdown(app.UIFigure);
            app.PlaybackDropDown_2.Items = {'Very Slow', 'Slow', 'Normal', 'Fast', 'Very Fast'};
            app.PlaybackDropDown_2.ItemsData = {'0.25', '0.5', '1', '1.5', '2.0'};
            app.PlaybackDropDown_2.Value = '1';
            app.TransitionButton = uibutton(app.UIFigure, 'push');
            app.TransitionButton.Enable = 'off';
            app.TransitionButton.Text = 'Transition';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.DJMixerLabel = uilabel(app.UIFigure);
            app.DJMixerLabel.Text = 'DJ Mixer';
            app.StudentNameLabel = uilabel(app.UIFigure);
            app.StudentNameLabel.Text = 'Student Name:';
            app.StudentIDLabel = uilabel(app.UIFigure);
            app.StudentIDLabel.Text = 'Student ID:';
            app.ABDULLAHISHEHULabel = uilabel(app.UIFigure);
            app.ABDULLAHISHEHULabel.Text = 'ABDULLAHI SHEHU';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '18431658';
            app.HelpButton = uibutton(app.UIFigure, 'state');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoundTransitionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end